classdef ARTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ARToolUIFigure       matlab.ui.Figure
        HomeMenu             matlab.ui.container.Menu
        AboutMenu            matlab.ui.container.Menu
        TabGroup             matlab.ui.container.TabGroup
        ToolsTab             matlab.ui.container.Tab
        ExcelEditFieldLabel  matlab.ui.control.Label
        ExcelEditField       matlab.ui.control.EditField
        ModelEditFieldLabel  matlab.ui.control.Label
        ModelEditField       matlab.ui.control.EditField
        ChooseButton         matlab.ui.control.Button
        ChooseButton_2       matlab.ui.control.Button
        ActionButton         matlab.ui.control.Button
        CancleButton         matlab.ui.control.Button
        TODOTab              matlab.ui.container.Tab
    end


    methods (Access = public)
        
        function [file,path] = OpenFile(~,fileType,title)
            [file,path] = uigetfile(fileType,title);
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            
        end
        
        % 模型和接口文件一致性检查校验
        function results = Check(app)


            results = 1;
            
            disp(results)
            
        end
    
    % 用户信息提示栏
        
        function  RemindDlg(~,chose,msg,title)
            
            if    chose ==1
            
                msgbox(msg,title);
            elseif  chose ==2
            
                helpdlg(msg,title)
            
            elseif  chose ==3
            
                warndlg(msg,title);
            
            elseif chose == 4
            
                errordlg(msg,title);
            
            end            
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.ARToolUIFigure
            
            
            app.ExcelEditField.Enable=false;
            
            app.ModelEditField.Enable =false;
            
            disp("Welcome");
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            msgbox("https://github.com/ilikui/ARTool","About",'help');
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            
            [file,Path]=OpenFile(app,'.xlsx','选择Excel接口文件');
            
            fullFilePath = fullfile(Path,file);
            
            app.ExcelEditField.Value=fullFilePath;


        end

        % Button pushed function: ChooseButton_2
        function ChooseButton_2Pushed(app, event)
            [file,Path]=OpenFile(app,'.slx','选择模型文件');
            
            fullFilePath = fullfile(Path,file);
            
            app.ModelEditField.Value = fullFilePath;
            
        end

        % Button pushed function: ActionButton
        function ActionButtonPushed(app, event)
            RemindDlg(app,4,'你好','23');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ARToolUIFigure = uifigure;
            app.ARToolUIFigure.Name = 'ARTool';
            app.HomeMenu = uimenu(app.ARToolUIFigure);
            app.HomeMenu.Text = 'Home';
            app.AboutMenu = uimenu(app.ARToolUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.ARToolUIFigure);
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.ExcelEditFieldLabel = uilabel(app.ToolsTab);
            app.ExcelEditFieldLabel.Text = 'Excel';
            app.ExcelEditField = uieditfield(app.ToolsTab, 'text');
            app.ModelEditFieldLabel = uilabel(app.ToolsTab);
            app.ModelEditFieldLabel.Text = 'Model';
            app.ModelEditField = uieditfield(app.ToolsTab, 'text');
            app.ChooseButton = uibutton(app.ToolsTab, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.ChooseButton_2 = uibutton(app.ToolsTab, 'push');
            app.ChooseButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseButton_2Pushed, true);
            app.ChooseButton_2.Text = 'Choose';
            app.ActionButton = uibutton(app.ToolsTab, 'push');
            app.ActionButton.ButtonPushedFcn = createCallbackFcn(app, @ActionButtonPushed, true);
            app.ActionButton.Text = 'Action';
            app.CancleButton = uibutton(app.ToolsTab, 'push');
            app.CancleButton.Text = 'Cancle';
            app.TODOTab = uitab(app.TabGroup);
            app.TODOTab.Title = 'TODO';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ARTool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ARToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ARToolUIFigure)
        end
    end
end