classdef iCIBA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
        Label     matlab.ui.control.Label
        Label2    matlab.ui.control.Label
        Label3    matlab.ui.control.Label
    end


    properties (Access = public)
        returnAudio % Description
        
        returnFs
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        
        % set
        
        app.UIFigure.Name  ="每日一句英文";
        
        app.Button.Text = "Play";


        urlApi = 'http://open.iciba.com/dsapi/';
        resutJson =webread(urlApi, weboptions('ContentType','json'));
        returnImage = webread( resutJson.picture2,weboptions('ContentType','image'));
        [app.returnAudio,app.returnFs] = webread(resutJson.tts,weboptions('ContentType','audio'));


        app.UIAxes.Title.String= " ";
        app.UIAxes.XLabel.String = " ";
        
        app.UIAxes.YLabel.String = " ";
        disp(app.UIAxes.XAxis)
        
        app.Label.Text =resutJson.content;
        
        
        app.Label2.Text =resutJson.note;
        
        app.Label3.Text = resutJson.caption;
        
        imshow(returnImage,'parent',app.UIAxes)


           % app.Label.BackgroundColor =returnImage;

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           % 
           
           sound(app.returnAudio,app.returnFs)
             
             
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = iCIBA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end